classdef audio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Practica2AudioUIFigure          matlab.ui.Figure
        ConfiguracindelaOperacinPanel   matlab.ui.container.Panel
        DesplazaralaizquierdaCheckBox   matlab.ui.control.CheckBox
        OperacinKnobLabel               matlab.ui.control.Label
        OperacionKnob                   matlab.ui.control.DiscreteKnob
        FactordeOperacinSliderLabel     matlab.ui.control.Label
        FactordeOperacionSlider         matlab.ui.control.Slider
        TipodeinterpoladoDropDownLabel  matlab.ui.control.Label
        TipodeinterpoladoDropDown       matlab.ui.control.DropDown
        SealPrincipalSwitchLabel        matlab.ui.control.Label
        SealPrincipalSwitch             matlab.ui.control.Switch
        ReproducirSalidaButton          matlab.ui.control.Button
        ReproducirAButton               matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        AButton                         matlab.ui.control.RadioButton
        BButton                         matlab.ui.control.RadioButton
        GuardarenLabel                  matlab.ui.control.Label
        SuperponerACheckBox             matlab.ui.control.CheckBox
        AtrsButton                      matlab.ui.control.Button
        GrabaraudioButton               matlab.ui.control.Button
        ReproducirBButton               matlab.ui.control.Button
        SalidaAxes                      matlab.ui.control.UIAxes
        EntradasAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Operacion Operaciones = Operaciones.Convolucion
        Interpolado Interpolacion_audio = Interpolacion_audio.Cero
        RecorderA audiorecorder = audiorecorder
        RecorderB audiorecorder = audiorecorder
        GrabarA (1, 1) logical = 1
        SuperponerA (1,1) logical = 0
        OperarA (1, 1) logical = 1
        SignalA = zeros(1, 8000*3)
        SignalB = zeros(1, 8000*3)
        Salida = zeros(1, 8000*3)
        kAxisX = (0:8000*3-1)
        time = (0:8000*3-1)
        Factor = 5
        DesplazarDerecha (1, 1) logical = 1
    end
    
    methods (Access = private)
        
        function graficarEntradas(app)
            hold(app.EntradasAxes, "off");
            grid(app.EntradasAxes,'on');
            app.time = app.kAxisX; %La entrada es del mismo tamaño
            
            if app.SuperponerA
                stem(app.EntradasAxes, app.time, app.SignalB,...
                    'Color', '#66d9be', 'Marker', 'none');
                hold(app.EntradasAxes, "on");
                stem(app.EntradasAxes, app.time, app.SignalA,...
                    'Color', '#7d568c', 'Marker', 'none');
            else
                stem(app.EntradasAxes, app.time, app.SignalA,...
                    'Color', '#7d568c', 'Marker', 'none');
                hold(app.EntradasAxes, "on");
                stem(app.EntradasAxes, app.time, app.SignalB,...
                    'Color', '#66d9be', 'Marker', 'none');
            end
        end
        
        function graficarSalida(app)
            grid(app.SalidaAxes,'on');
            stem(app.SalidaAxes, app.time, app.Salida,...
                    'Color', '#ff5ebf', 'Marker', 'none');
        end
        
        function llamarOperacion(app)
            switch app.Operacion
                case Operaciones.Desplazar
                    app.desplazar();
                case Operaciones.Reflejar
                    app.reflejar();
                case Operaciones.Atenuar
                    app.atenuar();
                case Operaciones.Sumar
                    app.sumar();
                case Operaciones.Convolucion
                    app.convolucion();
                case Operaciones.Restar
                    app.restar();
                case Operaciones.Diezmar
                    app.diezmar();
                case Operaciones.Interpolar
                    app.interpolar();
                otherwise
                    app.amplificar();
            end
            
            app.graficarSalida();
        end
        
        function interpolar(app)
            if app.OperarA
                signal = app.SignalA;
            else
                signal = app.SignalB;
            end
            
            rango = 0:length(signal) * app.Factor -1;
            
            if app.Factor == 0
                app.time = app.kAxisX;
                app.Salida = zeros(length(app.time), 1);
                return;
            end
            
            switch app.Interpolado
                case Interpolacion_audio.Cero
                    app.Salida = zeros(length(signal)*app.Factor, 1);
                    app.Salida(1:app.Factor:end) = signal;
                case Interpolacion_audio.Escalon
                    app.Salida = interp1(app.kAxisX * app.Factor,...
                        signal, rango, 'previous');
                otherwise
                    app.Salida = interp1(app.kAxisX * app.Factor,...
                        signal, rango, 'linear');
            end
            
            disp(app.Salida);
            app.Salida(isnan(app.Salida)) = 0;
            app.time = rango;
        end
        
        function diezmar(app)
            app.time = app.kAxisX;
            
            if app.OperarA
                signal = app.SignalA;
            else
                signal = app.SignalB;
            end
            
            if fix(app.Factor) == 0
                app.Salida = signal;
                return;
            end
            
            app.Salida = decimate(signal, fix(app.Factor));
            app.time = 0:length(app.Salida)-1;
        end
        
        function desplazar(app)
            app.time = app.kAxisX;
            %Las entradas son del mismo tamaño
            porcentaje = app.Factor/100;
            desplazamiento = fix(porcentaje * length(app.SignalA));
            
            if app.OperarA
                signal = app.SignalA;
            else
                signal = app.SignalB;
            end
            
            if desplazamiento == 0
                app.Salida = signal;
                return;
            end
            
            if app.DesplazarDerecha
                app.Salida = [zeros(desplazamiento, 1);...
                    signal(1:end-desplazamiento)];
            else
                app.Salida = [signal(1+desplazamiento:end);...
                    zeros(desplazamiento, 1)];
            end
        end
        
        function atenuar(app)
            app.time = app.kAxisX;
            
            if app.OperarA
                app.Salida = app.SignalA / app.Factor;
            else
                app.Salida = app.SignalB / app.Factor;
            end
        end
        
        function amplificar(app)
            app.time = app.kAxisX;
            
            if app.OperarA
                app.Salida = app.SignalA * app.Factor;
            else
                app.Salida = app.SignalB * app.Factor;
            end
        end
        
        function sumar(app)
            app.time = app.kAxisX;
            app.Salida = app.SignalA + app.SignalB;
        end
        
        function restar(app)
            app.time = app.kAxisX;
            
            if app.OperarA
                app.Salida = app.SignalA - app.SignalB;
            else
                app.Salida = app.SignalB - app.SignalA;
            end
        end
        
        function reflejar(app)
            app.time = app.kAxisX;
            
            if app.OperarA
                app.Salida = flip(app.SignalA);
            else
                app.Salida = flip(app.SignalB);
            end
        end
        
        function convolucion(app)
            app.Salida = conv(app.SignalA, app.SignalB);
            app.time = 0:length(app.Salida)-1;
            app.time = app.time;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GrabaraudioButton
        function GrabaraudioButtonPushed(app, event)
            window = msgbox('Grabando audio');
            
            if app.GrabarA
                recordblocking(app.RecorderA, 3);
                app.SignalA = getaudiodata(app.RecorderA);
            else
                recordblocking(app.RecorderB, 3);
                app.SignalB = getaudiodata(app.RecorderB);
            end
            
            close(window);
            app.graficarEntradas();
            app.llamarOperacion();
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            switch get(selectedButton, 'tag')
                case 'A'
                    app.GrabarA = true;
                otherwise
                    app.GrabarA = false;
            end
        end

        % Value changed function: SuperponerACheckBox
        function SuperponerACheckBoxValueChanged(app, event)
            isChecked = app.SuperponerACheckBox.Value;
            app.SuperponerA = logical(isChecked);
            app.graficarEntradas();
        end

        % Button pushed function: ReproducirAButton
        function ReproducirAButtonPushed(app, event)
            sound(app.SignalA);
        end

        % Button pushed function: ReproducirBButton
        function ReproducirBButtonPushed(app, event)
            sound(app.SignalB);
        end

        % Value changed function: OperacionKnob
        function OperacionKnobValueChanged(app, event)
            value = app.OperacionKnob.Value;
            
            switch value
                case 'Desplazar %'
                    app.Operacion = Operaciones.Desplazar;
                case 'Reflejar'
                    app.Operacion = Operaciones.Reflejar;
                case 'Atenuar'
                    app.Operacion = Operaciones.Atenuar;
                case 'Sumar'
                    app.Operacion = Operaciones.Sumar;
                case 'Convolución'
                    app.Operacion = Operaciones.Convolucion;
                case 'Restar'
                    app.Operacion = Operaciones.Restar;
                case 'Diezmar'
                    app.Operacion = Operaciones.Diezmar;
                case 'Interpolar'
                    app.Operacion = Operaciones.Interpolar;
                otherwise
                    app.Operacion = Operaciones.Amplificar;
            end
            
            app.llamarOperacion();
        end

        % Value changed function: FactordeOperacionSlider
        function FactordeOperacionSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Factor = fix(changingValue);
            
            if app.Operacion == Operaciones.Desplazar ||...
                    app.Operacion == Operaciones.Atenuar ||...
                    app.Operacion == Operaciones.Amplificar ||...
                    app.Operacion == Operaciones.Diezmar ||...
                    app.Operacion == Operaciones.Interpolar
                app.llamarOperacion();
            end
        end

        % Value changed function: SealPrincipalSwitch
        function SealPrincipalSwitchValueChanged(app, event)
            value = app.SealPrincipalSwitch.Value;
            app.OperarA = value == 'A';
            app.llamarOperacion();
        end

        % Value changed function: TipodeinterpoladoDropDown
        function TipodeinterpoladoDropDownValueChanged(app, event)
            value = app.TipodeinterpoladoDropDown.Value;
            
            switch value
                case 'Cero'
                    app.Interpolado = Interpolacion_audio.Cero;
                case 'Escalon'
                    app.Interpolado = Interpolacion_audio.Escalon;
                otherwise
                    app.Interpolado = Interpolacion_audio.Lineal;
            end
            
            if app.Operacion == Operaciones.Interpolar
                app.llamarOperacion();
            end
        end

        % Button pushed function: ReproducirSalidaButton
        function ReproducirSalidaButtonPushed(app, event)
            sound(app.Salida);
        end

        % Value changed function: DesplazaralaizquierdaCheckBox
        function DesplazaralaizquierdaCheckBoxValueChanged(app, event)
            isChecked = app.DesplazaralaizquierdaCheckBox.Value;
            app.DesplazarDerecha = ~logical(isChecked);
            app.llamarOperacion();
        end

        % Button pushed function: AtrsButton
        function AtrsButtonPushed(app, event)
           close("all","force");
           Menu;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Practica2AudioUIFigure = uifigure('Visible', 'off');
            app.Practica2AudioUIFigure.Name = 'Practica 2 Audio';
            app.ConfiguracindelaOperacinPanel = uipanel(app.Practica2AudioUIFigure);
            app.ConfiguracindelaOperacinPanel.Title = 'Configuración de la Operación';
            app.DesplazaralaizquierdaCheckBox = uicheckbox(app.ConfiguracindelaOperacinPanel);
            app.DesplazaralaizquierdaCheckBox.ValueChangedFcn = createCallbackFcn(app, @DesplazaralaizquierdaCheckBoxValueChanged, true);
            app.DesplazaralaizquierdaCheckBox.Text = 'Desplazar a la izquierda';
            app.OperacinKnobLabel = uilabel(app.ConfiguracindelaOperacinPanel);
            app.OperacinKnobLabel.Text = 'Operación';
            app.OperacionKnob = uiknob(app.ConfiguracindelaOperacinPanel, 'discrete');
            app.OperacionKnob.Items = {'Desplazar %', 'Reflejar', 'Atenuar', 'Sumar', 'Convolución', 'Restar', 'Diezmar', 'Interpolar', 'Amplificar'};
            app.OperacionKnob.ValueChangedFcn = createCallbackFcn(app, @OperacionKnobValueChanged, true);
            app.OperacionKnob.Value = 'Convolución';
            app.FactordeOperacinSliderLabel = uilabel(app.ConfiguracindelaOperacinPanel);
            app.FactordeOperacinSliderLabel.Text = 'Factor de Operación';
            app.FactordeOperacionSlider = uislider(app.ConfiguracindelaOperacinPanel);
            app.FactordeOperacionSlider.Limits = [0 50];
            app.FactordeOperacionSlider.ValueChangedFcn = createCallbackFcn(app, @FactordeOperacionSliderValueChanging, true);
            app.FactordeOperacionSlider.Value = 5;
            app.TipodeinterpoladoDropDownLabel = uilabel(app.ConfiguracindelaOperacinPanel);
            app.TipodeinterpoladoDropDownLabel.Text = 'Tipo de interpolado';
            app.TipodeinterpoladoDropDown = uidropdown(app.ConfiguracindelaOperacinPanel);
            app.TipodeinterpoladoDropDown.Items = {'Cero', 'Escalón', 'Lineal'};
            app.TipodeinterpoladoDropDown.ItemsData = {'Cero', 'Escalon', 'Lineal'};
            app.TipodeinterpoladoDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodeinterpoladoDropDownValueChanged, true);
            app.TipodeinterpoladoDropDown.Value = 'Cero';
            app.SealPrincipalSwitchLabel = uilabel(app.ConfiguracindelaOperacinPanel);
            app.SealPrincipalSwitchLabel.Text = 'Señal Principal';
            app.SealPrincipalSwitch = uiswitch(app.ConfiguracindelaOperacinPanel, 'slider');
            app.SealPrincipalSwitch.Items = {'A', 'B'};
            app.SealPrincipalSwitch.ValueChangedFcn = createCallbackFcn(app, @SealPrincipalSwitchValueChanged, true);
            app.SealPrincipalSwitch.Value = 'A';
            app.ReproducirSalidaButton = uibutton(app.Practica2AudioUIFigure, 'push');
            app.ReproducirSalidaButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirSalidaButtonPushed, true);
            app.ReproducirSalidaButton.Text = 'Reproducir Salida';
            app.ReproducirAButton = uibutton(app.Practica2AudioUIFigure, 'push');
            app.ReproducirAButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirAButtonPushed, true);
            app.ReproducirAButton.Text = 'Reproducir A';
            app.ButtonGroup = uibuttongroup(app.Practica2AudioUIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.AButton = uiradiobutton(app.ButtonGroup);
            app.AButton.Tag = 'A';
            app.AButton.Text = 'A';
            app.AButton.Value = true;
            app.BButton = uiradiobutton(app.ButtonGroup);
            app.BButton.Tag = 'B';
            app.BButton.Text = 'B';
            app.GuardarenLabel = uilabel(app.Practica2AudioUIFigure);
            app.GuardarenLabel.Text = 'Guardar en:';
            app.SuperponerACheckBox = uicheckbox(app.Practica2AudioUIFigure);
            app.SuperponerACheckBox.ValueChangedFcn = createCallbackFcn(app, @SuperponerACheckBoxValueChanged, true);
            app.SuperponerACheckBox.Text = 'Superponer A';
            app.AtrsButton = uibutton(app.Practica2AudioUIFigure, 'push');
            app.AtrsButton.ButtonPushedFcn = createCallbackFcn(app, @AtrsButtonPushed, true);
            app.AtrsButton.Text = 'Atrás';
            app.GrabaraudioButton = uibutton(app.Practica2AudioUIFigure, 'push');
            app.GrabaraudioButton.ButtonPushedFcn = createCallbackFcn(app, @GrabaraudioButtonPushed, true);
            app.GrabaraudioButton.Text = 'Grabar audio';
            app.ReproducirBButton = uibutton(app.Practica2AudioUIFigure, 'push');
            app.ReproducirBButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirBButtonPushed, true);
            app.ReproducirBButton.Text = 'Reproducir B';
            app.SalidaAxes = uiaxes(app.Practica2AudioUIFigure);
            title(app.SalidaAxes, 'Salida')
            xlabel(app.SalidaAxes, 'n')
            ylabel(app.SalidaAxes, 'Intensidad')
            app.EntradasAxes = uiaxes(app.Practica2AudioUIFigure);
            title(app.EntradasAxes, 'Entrada')
            xlabel(app.EntradasAxes, 'n')
            ylabel(app.EntradasAxes, 'Intensidad')
            % Show the figure after all components are created
            app.Practica2AudioUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audio

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Practica2AudioUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.Practica2AudioUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Practica2AudioUIFigure)
        end
    end
end